classdef InstrumentationAppReflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        PlotPhase                       matlab.ui.control.UIAxes
        PlotTime                        matlab.ui.control.UIAxes
        PlotFreq                        matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        ScaleButtonGroup                matlab.ui.container.ButtonGroup
        ButtonGroupPhase                matlab.ui.container.ButtonGroup
        LogLogButtonPhase               matlab.ui.control.ToggleButton
        LogYButtonPhase                 matlab.ui.control.ToggleButton
        LogXButtonPhase                 matlab.ui.control.ToggleButton
        LinearButtonPhase               matlab.ui.control.ToggleButton
        PhaseLabel                      matlab.ui.control.Label
        AmplitudeLabel                  matlab.ui.control.Label
        ExpDec                          matlab.ui.control.NumericEditField
        ExpWindDecrementEditFieldLabel  matlab.ui.control.Label
        RerunButton                     matlab.ui.control.Button
        LogLogButton                    matlab.ui.control.ToggleButton
        LogYButton                      matlab.ui.control.ToggleButton
        LogXButton                      matlab.ui.control.ToggleButton
        LinearButton                    matlab.ui.control.ToggleButton
        PanelDoor                       matlab.ui.container.Panel
        TauDoor                         matlab.ui.control.NumericEditField
        tausLabel                       matlab.ui.control.Label
        PanelTime                       matlab.ui.container.Panel
        Reset                           matlab.ui.control.Button
        Frequency                       matlab.ui.control.NumericEditField
        FrequencyHzLabel                matlab.ui.control.Label
        Amplitude                       matlab.ui.control.NumericEditField
        AmplitudemLabel                 matlab.ui.control.Label
        Period                          matlab.ui.control.NumericEditField
        TotalPeriodsLabel               matlab.ui.control.Label
        Fs                              matlab.ui.control.NumericEditField
        SampleFreqHzLabel               matlab.ui.control.Label
        SignalType                      matlab.ui.control.DropDown
        SignalTypeLabel                 matlab.ui.control.Label
        SDOFImage                       matlab.ui.control.Image
        SDOFParameters                  matlab.ui.container.Panel
        InputType                       matlab.ui.control.DropDown
        InputTypeDropDownLabel          matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        SimulTime                       matlab.ui.control.NumericEditField
        SimulTimesEditFieldLabel        matlab.ui.control.Label
        SampFreqSDOF                    matlab.ui.control.NumericEditField
        SampFreqHzEditFieldLabel        matlab.ui.control.Label
        Damping                         matlab.ui.control.NumericEditField
        DampingNsmEditFieldLabel        matlab.ui.control.Label
        Stiffness                       matlab.ui.control.NumericEditField
        StiffnessNmEditFieldLabel       matlab.ui.control.Label
        Mass                            matlab.ui.control.NumericEditField
        MasskgEditFieldLabel            matlab.ui.control.Label
        PanelFreq                       matlab.ui.container.Panel
        UpdateFreqAnalysisButton        matlab.ui.control.Button
        Scale                           matlab.ui.control.DropDown
        ScaleLabel                      matlab.ui.control.Label
        Output                          matlab.ui.control.DropDown
        OutputLabel                     matlab.ui.control.Label
        PanelPlotting                   matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        SubtractSignalButton            matlab.ui.control.RadioButton
        AddSignalButton                 matlab.ui.control.RadioButton
        SimplePlotButton                matlab.ui.control.RadioButton
        PlotButton                      matlab.ui.control.Button
        Restart                         matlab.ui.control.Button
        Menu                            matlab.ui.container.Menu
        OpenFileMenu                    matlab.ui.container.Menu
        SignalGeneratorMenu             matlab.ui.container.Menu
        SDOFSystemMenu                  matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        counter         % Signal Counter
        matrix_signals  % Signals Matrix
    end

    methods (Access = private)
        function [signal,t] = SimpleTimePlotting(app)
            % TIME PLOT
            value = app.SignalType.Value;
            switch true
                case (strcmpi(value,'Sine')==1)             % SINE
                    A = app.Amplitude.Value;                % Amplitude
                    sampleFreq = app.Fs.Value;              % Sample Frequency
                    freq = app.Frequency.Value;	            % Frequency
                    omega = 2*pi*freq;                      % Omega
                    T = app.Period.Value;                   % Total Period
                    t = 0:(1/sampleFreq):(T);               % Time Vector
                    signal = A*sin(omega*t);                % y(t) - Sine
                    plot(app.PlotTime,t,signal,'r','linewidth',3)

                case (strcmpi(value, 'Door')==1)                % DOOR
                    tau = app.TauDoor.Value;                    % Tau
                    A = app.Amplitude.Value;                    % Amplitude
                    sampleFreq = app.Fs.Value;                  % Sample Frequency
                    T = app.Period.Value;                       % Total Period
                    t = 0:(1/sampleFreq):(T);                   % Time Vector
                    signal = A*(heaviside(t) - heaviside(t-tau));% y(t) - Door
                    plot(app.PlotTime,t,signal,'r','linewidth',3)

                case (strcmpi(value, 'Random')==1)          % RANDOM
                    A = app.Amplitude.Value;                % Amplitude
                    sampleFreq = app.Fs.Value;              % Sample Frequency
                    T = app.Period.Value;                   % Total Period
                    t = 0:(1/sampleFreq):(T);               % Time Vector
                    signal = A*randn(length(t),1);          % y(t) - Random
                    plot(app.PlotTime,t,signal,'r','linewidth',3)
            end
        end

        function AdvancedTimePlotting(app,signal,time)
            plot(app.PlotTime,time,signal,'r','linewidth',3)
        end

        function freqPlotting(app,signal)
            % FREQUENCY PLOT
            value1 = app.Output.Value;      % Output Type Frequency
            switch true
                case (strcmpi(value1,'FFT')==1)             % FFT
                    sampleFreq = app.Fs.Value;              % Sample Frequency
                    N = length(signal);                     % Length Signal
                    FFTSignal = fft(signal,N)/N;
                    FFTAmplitude = 2*abs(FFTSignal(1:N/2+1));
                    FFTPhase = 2*rad2deg(unwrap(angle(FFTSignal(1:N/2+1))));
                    FFTFrequency = sampleFreq/2*linspace(0,1,N/2+1);

                    plot(app.PlotFreq,FFTFrequency,FFTAmplitude,'b','linewidth',3)
                    xlabel(app.PlotFreq, 'Frequency [Hz]')
                    ylabel(app.PlotFreq, 'FFT Y')
                    app.PlotFreq.XLim = [0 sampleFreq/2];

                    plot(app.PlotPhase,FFTFrequency,FFTPhase,'m','linewidth',3)
                    xlabel(app.PlotPhase, 'Frequency [Hz]')
                    ylabel(app.PlotPhase, 'Phase of FFT Y [degrees]')
                    app.PlotPhase.XLim = [0 sampleFreq/2];

                case (strcmpi(value1,'PSD')==1)         	% PSD
                    [Y,PSDfreq] = cpsd(signal,signal);

                    plot(app.PlotFreq,PSDfreq,Y,'b','linewidth',3)
                    xlabel(app.PlotFreq, 'Frequency [Hz]')
                    ylabel(app.PlotFreq, 'PSD Y')
                    app.PlotFreq.XLim = [0 max(PSDfreq)];

                    cla(app.PlotPhase)

                case (strcmpi(value1,'Autocorrelation')==1) % Autocorrelation
                    [Rxx,lags] = xcorr(signal,signal);

                    plot(app.PlotFreq,lags,Rxx,'b','linewidth',3)
                    xlabel(app.PlotFreq, 'Time [s]')
                    ylabel(app.PlotFreq, 'ACR')
                    app.PlotFreq.XLim = [min(lags) max(lags)];

                    cla(app.PlotPhase)
            end
            value2 = app.Scale.Value;       % Data Scale
            switch true
                case (strcmpi(value2,'Linear')==1)
                    app.PlotFreq.XScale = 'linear';
                    app.PlotFreq.YScale = 'linear';
                    app.PlotPhase.XScale = 'linear';
                    app.PlotPhase.YScale = 'linear';
                case (strcmpi(value2,'LogY')==1)
                    app.PlotFreq.XScale = 'linear';
                    app.PlotFreq.YScale = 'log';
                    app.PlotPhase.XScale = 'linear';
                    app.PlotPhase.YScale = 'log';
                case (strcmpi(value2,'LogX')==1)
                    app.PlotFreq.XScale = 'log';
                    app.PlotFreq.YScale = 'linear';
                    app.PlotPhase.XScale = 'log';
                    app.PlotPhase.YScale = 'linear';
                case (strcmpi(value2,'LogLog')==1)
                    app.PlotFreq.XScale = 'log';
                    app.PlotFreq.YScale = 'log';
                    app.PlotPhase.XScale = 'log';
                    app.PlotPhase.YScale = 'log';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {720, 720};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {1015, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: SignalGeneratorMenu
        function SignalGeneratorMenuSelected(app, event)
            app.PanelTime.Visible = 'on';   % Initializing
            app.PanelPlotting.Visible = 'on';
            app.SubtractSignalButton.Enable = 'off';
            app.AddSignalButton.Enable = 'off';
            app.PanelFreq.Visible = 'on';
            app.PlotButton.Visible = 'on';
            app.PanelDoor.Visible = 'off';
            app.UpdateFreqAnalysisButton.Enable = 'off';
            app.Restart.Visible = 'on';
            app.SDOFParameters.Visible = 'off';
            app.SDOFImage.Visible = 'off';
            app.ScaleButtonGroup.Visible = 'off';
            app.counter = 1;                % Signal Counter
        end

        % Menu selected function: SDOFSystemMenu
        function SDOFSystemMenuSelected(app, event)
            app.PanelTime.Visible = 'off';   % Initializing
            app.PanelPlotting.Visible = 'off';
            app.SubtractSignalButton.Enable = 'off';
            app.AddSignalButton.Enable = 'off';
            app.PanelFreq.Visible = 'off';
            app.PlotButton.Visible = 'off';
            app.PanelDoor.Visible = 'off';
            app.UpdateFreqAnalysisButton.Enable = 'off';
            app.Restart.Visible = 'on';
            app.SDOFParameters.Visible = 'on';
            app.SDOFImage.Visible = 'on';
            app.ScaleButtonGroup.Visible = 'off';
            app.counter = 1;                % Signal Counter
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            cla(app.PlotTime)
            cla(app.PlotFreq)
            cla(app.PlotPhase)
            
            %% VARIAVEIS
            m = app.Mass.Value;
            k = app.Stiffness.Value;
            c = app.Damping.Value;
            T = app.SimulTime.Value;
            fs = app.SampFreqSDOF.Value;
            dt = 1/fs;
            df = 1/T;
            t = 0:dt:T;
            f = 0:df:(fs/2);
            w = 2*pi*f;
            z = c/(2*sqrt(k*m));
            wn = sqrt(k/m);
            wd = wn*sqrt(1-z^2);

            value0 = app.InputType.Value;
            switch true
                case (strcmpi(value0,'Impulse')==1)
                    ht = 1/(m*wd)*exp(-z*wn*t).*sin(wd*t);
                    Hjw = 1./(k-w.^2*m+1i*w*c);

                    plot(app.PlotTime,t,ht,'r','linewidth',3)
                    xlabel(app.PlotTime, 'Time [s]')
                    ylabel(app.PlotTime, 'y(t) [m]')

                    plot(app.PlotFreq,f,abs(Hjw),'b','linewidth',3)
                    xlabel(app.PlotFreq, 'Frequency [Hz]')
                    ylabel(app.PlotFreq, 'Abs of Receptance [m/N]')
                    app.PlotFreq.XLim = [0 wn*2/2/pi];
                    app.PlotFreq.YLim = [0 1.1*max(abs(Hjw))];

                    plot(app.PlotPhase,f,rad2deg(unwrap(angle(Hjw))),'m','linewidth',3)
                    xlabel(app.PlotPhase, 'Frequency [Hz]')
                    ylabel(app.PlotPhase, 'Phase of Receptance [degrees]')
                    app.PlotPhase.XLim = [0 wn*2/2/pi];
                    app.PlotPhase.YLim = [-200 10];

                case (strcmpi(value0, 'Random')==1)
                    ht = 1/(m*wd)*exp(-z*wn*t).*sin(wd*t);

                    N = round(length(t));
                    ft = randn(1,length(t));
                    xt = conv(ht,ft)*dt;
                    xt = xt(1:length(ft));
                    [Fjw,f] = tfestimate(ft,xt,[],[],N,fs);

                    plot(app.PlotTime,t,xt,'r','linewidth',3)
                    xlabel(app.PlotTime, 'Time [s]')
                    ylabel(app.PlotTime, 'y(t) [m]')

                    plot(app.PlotFreq,f,abs(Fjw),'b','linewidth',3)
                    xlabel(app.PlotFreq, 'Frequency [Hz]')
                    ylabel(app.PlotFreq, 'Abs of Receptance [m/N]')
                    app.PlotFreq.XLim = [0 wn*2/2/pi];
                    app.PlotFreq.YLim = [0 1.1*max(abs(Fjw))];

                    plot(app.PlotPhase,f,rad2deg(unwrap(angle(Fjw))),'m','linewidth',3)
                    xlabel(app.PlotPhase, 'Frequency [Hz]')
                    ylabel(app.PlotPhase, 'Phase of Receptance [degrees]')
                    app.PlotPhase.XLim = [0 wn*2/2/pi];
                    app.PlotPhase.YLim = [-200 10];
            end
        end

        % Value changed function: SignalType
        function SignalTypeValueChanged(app, event)
            value = app.SignalType.Value;   % Toogle Visibility
            switch true                     % based on Selection
                case (strcmpi(value,'Sine')==1)
                    app.PanelTime.Visible = 'on';
                    app.Frequency.Enable = 'on';
                    app.PanelFreq.Visible = 'on';
                    app.PlotButton.Visible = 'on';
                    app.PanelDoor.Visible = 'off';
                case (strcmpi(value, 'Door')==1)
                    app.PanelTime.Visible = 'on';
                    app.Frequency.Enable = 'off';
                    app.PanelFreq.Visible = 'on';
                    app.PlotButton.Visible = 'on';
                    app.PanelDoor.Visible = 'on';
                case (strcmpi(value, 'Random')==1)
                    app.PanelTime.Visible = 'on';
                    app.Frequency.Enable = 'off';
                    app.PanelFreq.Visible = 'on';
                    app.PlotButton.Visible = 'on';
                    app.PanelDoor.Visible = 'off';
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonValueChanged(app, event)
            switch app.ButtonGroup.SelectedObject.Text
                case 'Simple Plot'
                    app.matrix_signals = [];
                    [signal,~] = SimpleTimePlotting(app);
                    freqPlotting(app,signal)
                    app.matrix_signals(app.counter,:) = signal;
                    app.SubtractSignalButton.Enable = 'on';
                    app.AddSignalButton.Enable = 'on';
                    app.Reset.Visible = 'on';
                    app.Fs.Enable = 'off';
                    app.Period.Enable = 'off';
                    app.UpdateFreqAnalysisButton.Enable = 'on';
                    save SavedSignal signal

                case 'Add Signal'
                    app.counter = app.counter + 1;
                    [signal,time] = SimpleTimePlotting(app);
                    app.matrix_signals(app.counter,:) = signal;
                    signal = sum(app.matrix_signals);
                    AdvancedTimePlotting(app,signal,time)
                    freqPlotting(app,signal)
                    save SavedSignal signal

                case 'Subtract Sign.'
                    app.counter = app.counter + 1;
                    [signal,time] = SimpleTimePlotting(app);
                    app.matrix_signals(app.counter,:) = -signal;
                    signal = sum(app.matrix_signals);
                    AdvancedTimePlotting(app,signal,time)
                    freqPlotting(app,signal)
                    save SavedSignal signal
            end
        end

        % Button pushed function: Restart
        function RestartButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            InstrumentationAppReflow();
            close(app.UIFigure)
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            app.SimplePlotButton.Value = 1;
            app.SubtractSignalButton.Enable = 'off';
            app.AddSignalButton.Enable = 'off';
            app.Fs.Enable = 'on';
            app.Period.Enable = 'on';
            app.Reset.Visible = 'off';
        end

        % Button pushed function: UpdateFreqAnalysisButton
        function UpdateFreqAnalysisButtonPushed(app, event)
            load SavedSignal %#ok<LOAD>
            freqPlotting(app,signal)
        end

        % Menu selected function: OpenFileMenu
        function OpenFileMenuSelected(app, event)
            cla(app.PlotTime)
            cla(app.PlotFreq)
            cla(app.PlotPhase)

            app.PanelTime.Visible = 'off';   % Initializing
            app.ScaleButtonGroup.Visible = 'on';
            app.PanelPlotting.Visible = 'off';
            app.SubtractSignalButton.Enable = 'off';
            app.AddSignalButton.Enable = 'off';
            app.PanelFreq.Visible = 'off';
            app.PlotButton.Visible = 'off';
            app.PanelDoor.Visible = 'off';
            app.UpdateFreqAnalysisButton.Enable = 'off';
            app.Restart.Visible = 'on';
            app.SDOFParameters.Visible = 'off';
            app.SDOFImage.Visible = 'off';

            [file,path,~] = uigetfile;
            filename = [path, file];
            load(filename); %#ok<LOAD>

            [nLin nCol] = size(X2);

            dt = X2(2,1);
            Fs = 1/dt;
            N = nLin;
            NFFT=2^nextpow2(N);
            df = Fs/NFFT;
            freq = 0:df:(Fs-df);

            FFSignal = fft(X1(:,2))/(NFFT/2);

            decrem = app.ExpDec.Value;

            t = X2(:,1);
            E = exp(-t*decrem);

            X2Win = zeros(nLin,nCol);
            for ii=1:nLin
                X2Win(ii,:) = E(ii)*X2(ii,:);
            end

            XXSignal = fft(X2Win(:,2))/(NFFT/2);

            save('SignalOpened.mat','FFSignal','XXSignal','X2Win','X2','H1_2','freq','NFFT')

            hold(app.PlotTime,'on')
            plot(app.PlotTime,X2(:,1),X2(:,2),'r','linewidth',4)
            plot(app.PlotTime,X2(:,1),X2Win(:,2),'k','linewidth',2)
            xlabel(app.PlotTime, 'Time [s]')
            ylabel(app.PlotTime, 'y(t) [m]')
            hold(app.PlotTime,'off')

            hold(app.PlotFreq,'on')
            plot(app.PlotFreq,H1_2(:,1),abs(H1_2(:,2)),'b','linewidth',4)
            plot(app.PlotFreq,freq,abs(XXSignal./FFSignal),'k','linewidth',2)
            xlabel(app.PlotFreq, 'Frequency [Hz]')
            ylabel(app.PlotFreq, 'Modulus [V/V]')
            app.PlotFreq.XLim = [0 max(H1_2(:,1))];
            hold(app.PlotFreq,'off')

            hold(app.PlotPhase,'on')
            plot(app.PlotPhase,H1_2(:,1),rad2deg(unwrap(angle(H1_2(:,2)))),'m','linewidth',4)
            plot(app.PlotPhase,freq,rad2deg(unwrap(angle(XXSignal./FFSignal))),'k','linewidth',2)
            xlabel(app.PlotPhase, 'Frequency [Hz]')
            ylabel(app.PlotPhase, 'Phase [degrees]')
            app.PlotPhase.XLim = [0 max(H1_2(:,1))];
            hold(app.PlotPhase,'off')

        end

        % Button pushed function: RerunButton
        function RerunButtonPushed(app, event)
            cla(app.PlotTime)
            cla(app.PlotFreq)
            cla(app.PlotPhase)

            load('SignalOpened.mat','FFSignal','XXSignal','X2Win','X2','H1_2','freq','NFFT')

            decrem = app.ExpDec.Value;

            t = X2(:,1);
            E = exp(-t*decrem);

            [nLin nCol] = size(X2);
            X2Win = zeros(nLin,nCol);
            for ii=1:nLin
                X2Win(ii,:) = E(ii)*X2(ii,:);
            end

            XXSignal = fft(X2Win(:,2))/(NFFT/2);

            hold(app.PlotTime,'on')
            plot(app.PlotTime,X2(:,1),X2(:,2),'r','linewidth',4)
            plot(app.PlotTime,X2(:,1),X2Win(:,2),'k','linewidth',2)
            xlabel(app.PlotTime, 'Time [s]')
            ylabel(app.PlotTime, 'y(t) [m]')
            hold(app.PlotTime,'off')

            hold(app.PlotFreq,'on')
            plot(app.PlotFreq,H1_2(:,1),abs(H1_2(:,2)),'b','linewidth',4)
            plot(app.PlotFreq,freq,abs(XXSignal./FFSignal),'k','linewidth',2)
            xlabel(app.PlotFreq, 'Frequency [Hz]')
            ylabel(app.PlotFreq, 'Modulus [V/V]')
            app.PlotFreq.XLim = [0 max(H1_2(:,1))];
            hold(app.PlotFreq,'off')

            hold(app.PlotPhase,'on')
            plot(app.PlotPhase,H1_2(:,1),rad2deg(unwrap(angle(H1_2(:,2)))),'m','linewidth',4)
            plot(app.PlotPhase,freq,rad2deg(unwrap(angle(XXSignal./FFSignal))),'k','linewidth',2)
            xlabel(app.PlotPhase, 'Frequency [Hz]')
            ylabel(app.PlotPhase, 'Phase [degrees]')
            app.PlotPhase.XLim = [0 max(H1_2(:,1))];
            hold(app.PlotPhase,'off')

            switch app.ScaleButtonGroup.SelectedObject.Text
                case 'Linear'
                    app.PlotFreq.XScale = 'linear';
                    app.PlotFreq.YScale = 'linear';

                case 'LogX'
                    app.PlotFreq.XScale = 'log';
                    app.PlotFreq.YScale = 'linear';

                case 'LogY'
                    app.PlotFreq.XScale = 'linear';
                    app.PlotFreq.YScale = 'log';

                case 'LogLog'
                    app.PlotFreq.XScale = 'log';
                    app.PlotFreq.YScale = 'log';
            end
            switch app.ButtonGroupPhase.SelectedObject.Text
                case 'Linear'
                    app.PlotPhase.XScale = 'linear';
                    app.PlotPhase.YScale = 'linear';

                case 'LogX'
                    app.PlotPhase.XScale = 'log';
                    app.PlotPhase.YScale = 'linear';

                case 'LogY'
                    app.PlotPhase.XScale = 'linear';
                    app.PlotPhase.YScale = 'log';

                case 'LogLog'
                    app.PlotPhase.XScale = 'log';
                    app.PlotPhase.YScale = 'log';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlotFreq = uiaxes(app.LeftPanel);
            xlabel(app.PlotFreq, 'Frequency [Hz]')
            ylabel(app.PlotFreq, 'Amplitude')
            app.PlotTime = uiaxes(app.LeftPanel);
            xlabel(app.PlotTime, 'Time [s]')
            ylabel(app.PlotTime, 'y(t) [m]')
            app.PlotPhase = uiaxes(app.LeftPanel);
            xlabel(app.PlotPhase, 'Frequency [Hz]')
            ylabel(app.PlotPhase, 'Phase')
            app.RightPanel = uipanel(app.GridLayout);
            app.Restart = uibutton(app.RightPanel, 'push');
            app.Restart.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.Restart.Visible = 'off';
            app.Restart.Text = 'Restart App';
            app.PanelPlotting = uipanel(app.RightPanel);
            app.PanelPlotting.Title = 'Plotting Options';
            app.PanelPlotting.Visible = 'off';
            app.PlotButton = uibutton(app.PanelPlotting, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonValueChanged, true);
            app.PlotButton.Text = 'Plot';
            app.ButtonGroup = uibuttongroup(app.PanelPlotting);
            app.SimplePlotButton = uiradiobutton(app.ButtonGroup);
            app.SimplePlotButton.Text = 'Simple Plot';
            app.SimplePlotButton.Value = true;
            app.AddSignalButton = uiradiobutton(app.ButtonGroup);
            app.AddSignalButton.Text = 'Add Signal';
            app.SubtractSignalButton = uiradiobutton(app.ButtonGroup);
            app.SubtractSignalButton.Text = 'Subtract Sign.';
            app.PanelFreq = uipanel(app.RightPanel);
            app.PanelFreq.Title = 'Frequency Analysis';
            app.PanelFreq.Visible = 'off';
            app.OutputLabel = uilabel(app.PanelFreq);
            app.OutputLabel.Text = 'Output';
            app.Output = uidropdown(app.PanelFreq);
            app.Output.Items = {'FFT', 'PSD', 'Autocorrelation'};
            app.Output.Value = 'FFT';
            app.ScaleLabel = uilabel(app.PanelFreq);
            app.ScaleLabel.Text = 'Scale';
            app.Scale = uidropdown(app.PanelFreq);
            app.Scale.Items = {'Linear', 'LogX', 'LogY', 'LogLog'};
            app.Scale.Value = 'LogX';
            app.UpdateFreqAnalysisButton = uibutton(app.PanelFreq, 'push');
            app.UpdateFreqAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateFreqAnalysisButtonPushed, true);
            app.UpdateFreqAnalysisButton.Text = 'Update Freq. Analysis';
            app.SDOFParameters = uipanel(app.RightPanel);
            app.SDOFParameters.Title = 'SDOF Parameters';
            app.SDOFParameters.Visible = 'off';
            app.MasskgEditFieldLabel = uilabel(app.SDOFParameters);
            app.MasskgEditFieldLabel.Text = 'Mass [kg]';
            app.Mass = uieditfield(app.SDOFParameters, 'numeric');
            app.Mass.Value = 5;
            app.StiffnessNmEditFieldLabel = uilabel(app.SDOFParameters);
            app.StiffnessNmEditFieldLabel.Text = 'Stiffness [N/m]';
            app.Stiffness = uieditfield(app.SDOFParameters, 'numeric');
            app.Stiffness.Value = 200;
            app.DampingNsmEditFieldLabel = uilabel(app.SDOFParameters);
            app.DampingNsmEditFieldLabel.Text = 'Damping [N.s/m]';
            app.Damping = uieditfield(app.SDOFParameters, 'numeric');
            app.Damping.Value = 10;
            app.SampFreqHzEditFieldLabel = uilabel(app.SDOFParameters);
            app.SampFreqHzEditFieldLabel.Text = 'Samp. Freq. [Hz]';
            app.SampFreqSDOF = uieditfield(app.SDOFParameters, 'numeric');
            app.SampFreqSDOF.Value = 1000;
            app.SimulTimesEditFieldLabel = uilabel(app.SDOFParameters);
            app.SimulTimesEditFieldLabel.Text = 'Simul. Time [s]';
            app.SimulTime = uieditfield(app.SDOFParameters, 'numeric');
            app.SimulTime.Value = 20;
            app.SimulateButton = uibutton(app.SDOFParameters, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.InputTypeDropDownLabel = uilabel(app.SDOFParameters);
            app.InputTypeDropDownLabel.Text = 'Input Type';
            app.InputType = uidropdown(app.SDOFParameters);
            app.InputType.Items = {'Impulse', 'Random'};
            app.InputType.Value = 'Impulse';
            app.SDOFImage = uiimage(app.RightPanel);
            app.SDOFImage.Visible = 'off';
            app.SDOFImage.ImageSource = 'DrawSDOF.png';
            app.PanelTime = uipanel(app.RightPanel);
            app.PanelTime.Title = 'Signal Parameters';
            app.PanelTime.Visible = 'off';
            app.SignalTypeLabel = uilabel(app.PanelTime);
            app.SignalTypeLabel.Text = 'Signal Type';
            app.SignalType = uidropdown(app.PanelTime);
            app.SignalType.Items = {'Sine', 'Door', 'Random'};
            app.SignalType.ValueChangedFcn = createCallbackFcn(app, @SignalTypeValueChanged, true);
            app.SignalType.Value = 'Sine';
            app.SampleFreqHzLabel = uilabel(app.PanelTime);
            app.SampleFreqHzLabel.Text = 'Sample Freq [Hz]';
            app.Fs = uieditfield(app.PanelTime, 'numeric');
            app.Fs.Value = 2000;
            app.TotalPeriodsLabel = uilabel(app.PanelTime);
            app.TotalPeriodsLabel.Text = 'Total Period [s]';
            app.Period = uieditfield(app.PanelTime, 'numeric');
            app.Period.Value = 1;
            app.AmplitudemLabel = uilabel(app.PanelTime);
            app.AmplitudemLabel.Text = 'Amplitude [m]';
            app.Amplitude = uieditfield(app.PanelTime, 'numeric');
            app.Amplitude.Value = 1;
            app.FrequencyHzLabel = uilabel(app.PanelTime);
            app.FrequencyHzLabel.Text = 'Frequency [Hz]';
            app.Frequency = uieditfield(app.PanelTime, 'numeric');
            app.Frequency.Value = 20;
            app.Reset = uibutton(app.PanelTime, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Visible = 'off';
            app.Reset.Text = 'Reset';
            app.PanelDoor = uipanel(app.RightPanel);
            app.PanelDoor.Visible = 'off';
            app.tausLabel = uilabel(app.PanelDoor);
            app.tausLabel.Interpreter = 'latex';
            app.tausLabel.Text = '$\tau$ [s]';
            app.TauDoor = uieditfield(app.PanelDoor, 'numeric');
            app.TauDoor.Value = 0.5;
            app.ScaleButtonGroup = uibuttongroup(app.RightPanel);
            app.ScaleButtonGroup.Title = 'Scale';
            app.ScaleButtonGroup.Visible = 'off';
            app.LinearButton = uitogglebutton(app.ScaleButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LogXButton = uitogglebutton(app.ScaleButtonGroup);
            app.LogXButton.Text = 'LogX';
            app.LogYButton = uitogglebutton(app.ScaleButtonGroup);
            app.LogYButton.Text = 'LogY';
            app.LogYButton.Value = true;
            app.LogLogButton = uitogglebutton(app.ScaleButtonGroup);
            app.LogLogButton.Text = 'LogLog';
            app.RerunButton = uibutton(app.ScaleButtonGroup, 'push');
            app.RerunButton.ButtonPushedFcn = createCallbackFcn(app, @RerunButtonPushed, true);
            app.RerunButton.Text = 'Rerun';
            app.ExpWindDecrementEditFieldLabel = uilabel(app.ScaleButtonGroup);
            app.ExpWindDecrementEditFieldLabel.Text = 'Exp.Wind. Decrement';
            app.ExpDec = uieditfield(app.ScaleButtonGroup, 'numeric');
            app.AmplitudeLabel = uilabel(app.ScaleButtonGroup);
            app.AmplitudeLabel.Text = 'Amplitude';
            app.PhaseLabel = uilabel(app.ScaleButtonGroup);
            app.PhaseLabel.Text = 'Phase';
            app.ButtonGroupPhase = uibuttongroup(app.ScaleButtonGroup);
            app.LinearButtonPhase = uitogglebutton(app.ButtonGroupPhase);
            app.LinearButtonPhase.Text = 'Linear';
            app.LinearButtonPhase.Value = true;
            app.LogXButtonPhase = uitogglebutton(app.ButtonGroupPhase);
            app.LogXButtonPhase.Text = 'LogX';
            app.LogYButtonPhase = uitogglebutton(app.ButtonGroupPhase);
            app.LogYButtonPhase.Text = 'LogY';
            app.LogLogButtonPhase = uitogglebutton(app.ButtonGroupPhase);
            app.LogLogButtonPhase.Text = 'LogLog';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.OpenFileMenu = uimenu(app.Menu);
            app.OpenFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFileMenuSelected, true);
            app.OpenFileMenu.Text = 'Open File';
            app.SignalGeneratorMenu = uimenu(app.Menu);
            app.SignalGeneratorMenu.MenuSelectedFcn = createCallbackFcn(app, @SignalGeneratorMenuSelected, true);
            app.SignalGeneratorMenu.Separator = 'on';
            app.SignalGeneratorMenu.Text = 'Signal Generator';
            app.SDOFSystemMenu = uimenu(app.Menu);
            app.SDOFSystemMenu.MenuSelectedFcn = createCallbackFcn(app, @SDOFSystemMenuSelected, true);
            app.SDOFSystemMenu.Text = 'SDOF System';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InstrumentationAppReflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end